classdef MainProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        backtostartButton     matlab.ui.control.Button
        DeviceDropDown        matlab.ui.control.DropDown
        DeviceDropDownLabel   matlab.ui.control.Label
        PlaySwitch            matlab.ui.control.Switch
        PlaySwitchLabel       matlab.ui.control.Label
        AmplitudeSlider       matlab.ui.control.Slider
        AmplitudeSliderLabel  matlab.ui.control.Label
        SaveButton            matlab.ui.control.Button
        BPMSpinner            matlab.ui.control.Spinner
        BPMSpinnerLabel       matlab.ui.control.Label
        PathEditField         matlab.ui.control.EditField
        PathEditFieldLabel    matlab.ui.control.Label
        OpenFileButton        matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        signal = [];
        audioDevice = 0; % urządzenia wyjścia
        adw = 0; %audio dewice writer do odtwarzania dźwięku
        afr = 0; %audio file rader do odtwarzania piosenki
        loops = 0; %liczba pętli/wskaźnik,czy pętla główna jest aktywna
        song = []   %tablica, w której tymczasowow przechowywane są dane całego utworu
        originalBpm = 0 %początkowe BPM piosenki
        audioInfo =[] %informacje o pliku
        speed = 0 %szybklość odtwarzania/zapisu piosenki
        background = 0; %background do funkcji asynchronicznej
    end
    
    methods (Access = private)
        
        function results = main(app) %główna pętla programu
            app.loops = 1;
            i = 1;
            f(i) = parfevalOnAll(@stretchAudio,1,app.afr()*app.AmplitudeSlider.Value,app.speed);
            while(app.loops == 1) %przetwarzanie sygnałuy w tle powoduje mniej zakłóceń i przerw w odtwarzaniu
                    app.signal = fetchOutputs(f(i));
                    app.adw(app.signal);
                    plot(app.UIAxes,app.signal);
                    i = i+1;
                    f(i) = parfevalOnAll(@stretchAudio,1,app.afr()*app.AmplitudeSlider.Value,app.speed);
                    pause(0.5/app.speed);
            end
            app.adw.release(); %po wyjściu pętli zatrzymuje adw, by wyczyścić bufor
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %definiowanie objektów
            app.adw = audioDeviceWriter(44100);
            app.audioDevice = getAudioDevices(app.adw);
            app.DeviceDropDown.Items = app.audioDevice;
            app.adw.Device = app.DeviceDropDown.Value;
            app.background = backgroundPool;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %wyłaczanie odtwarzania i czyszczenie buforu przed zamknięciem
            %aplikacji
            app.PlaySwitch.Value = "Off";
            app.PlaySwitchValueChanged();
            app.afr.release();
            delete(app);
        end

        % Value changed function: PlaySwitch
        function PlaySwitchValueChanged(app, event)
            %włącz/wyłącz główną pętle odtwarzania
            value = app.PlaySwitch.Value;
            if(value == "Off")
                app.loops = 0;
            end
            if(value == "On")
                app.main();%wywołąnie głównej petli, jeżeli takowa nie istnieje
            end
        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
%inicjalizowanie obiektów, otwieranie pliku,tworzenie kopii pliku na któym pracuje program,
            app.PlaySwitch.Value = 'Off';
            app.PlaySwitch.Enable = false;
            [fileName,path] = uigetfile( ...
{['*.wav;*.wma;*.avi;*.aif;*.aifc;*.aiff;*.mp3;*.au;*.snd;*.mp4;*.m4;' ...
'*.fac;*.ogg;*.mov'],...
    ['Audio FIles (*.wav;*.wma;*.avi;*.aif;*.aifc;*.aiff;*.mp3;*.au;' ...
    '*.snd;*.mp4;*.m4;*.fac;*.ogg;*.mov)'];}, ...
   'Select a File');
            if(fileName == 0)
            else
                release(app.adw);
                app.PathEditField.Value = [path fileName];            
                app.song = audioread(app.PathEditField.Value);
                app.audioInfo = audioinfo(app.PathEditField.Value);
                app.adw.SampleRate = app.audioInfo.SampleRate;
                app.originalBpm = tempo(app.song(:,1), 44100); %magiczna funkcja zwracajaca tempo utworu
                app.BPMSpinner.Value = app.originalBpm(2);
                audiowrite("file.wav",app.song,app.audioInfo.SampleRate);
                app.afr = dsp.AudioFileReader("file.wav","SamplesPerFrame",app.audioInfo.SampleRate/2);
                app.song = 0;
                app.audioInfo = 0;
                app.PlaySwitch.Enable = true;
                app.SaveButton.Visible = true;
                app.BPMSpinner.Limits = [app.originalBpm(2)*0.3 app.originalBpm(2)*2];
                app.BPMSpinnerValueChanged();
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %zapisywanie edytowanej piosenki do nowego pliku
            app.PlaySwitch.Value = "Off";
            app.PlaySwitchValueChanged();
            app.PlaySwitch.Enable = false; %wyłaćzenie możlwiości odtwarzania w trakcie pracy funkcji zapisującej
            fileName = uiputfile( ...
{'*.wav';'*.mp4';'*.oga';'*.m4a'; ...
'*.flac';'*.ogg'}, ...
   'Select a File');
            if(fileName == 0)
            else
                app.adw.release();
                app.song = audioread("file.wav");
                app.audioInfo = audioinfo("file.wav");
                audiowrite(fileName,stretchAudio(app.song*app.AmplitudeSlider.Value,app.BPMSpinner.Value/app.originalBpm(2)),app.audioInfo.SampleRate);
            end
            app.PlaySwitch.Enable = true;
        end

        % Value changed function: BPMSpinner
        function BPMSpinnerValueChanged(app, event)
            %zmiana tempa piosenki
            value = app.BPMSpinner.Value;
            app.PlaySwitch.Value = "Off";
            app.PlaySwitchValueChanged();
            app.adw.release();
            app.speed = value/app.originalBpm(2);
        end

        % Value changed function: DeviceDropDown
        function DeviceDropDownValueChanged(app, event)
            %zmiana urzadzenia odtwarzania
            value = app.DeviceDropDown.Value;
            app.adw.release();
            app.adw.Device = value;
        end

        % Button pushed function: backtostartButton
        function backtostartButtonPushed(app, event)
            %powrót do początku piosenki
            app.PlaySwitch.Value = "Off";
            app.PlaySwitchValueChanged();
            app.afr.release();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = {'-1'; '0.5'; '-0.6'; '-0.4'; '-0.2'; '0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.OpenFileButton = uibutton(app.UIFigure, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.PathEditFieldLabel = uilabel(app.UIFigure);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.BPMSpinnerLabel = uilabel(app.UIFigure);
            app.BPMSpinnerLabel.Text = 'BPM';
            app.BPMSpinner = uispinner(app.UIFigure);
            app.BPMSpinner.ValueChangedFcn = createCallbackFcn(app, @BPMSpinnerValueChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.Limits = [0 1];
            app.AmplitudeSlider.Value = 1;
            app.PlaySwitchLabel = uilabel(app.UIFigure);
            app.PlaySwitchLabel.Enable = 'off';
            app.PlaySwitchLabel.Text = 'Play';
            app.PlaySwitch = uiswitch(app.UIFigure, 'slider');
            app.PlaySwitch.ValueChangedFcn = createCallbackFcn(app, @PlaySwitchValueChanged, true);
            app.PlaySwitch.Enable = 'off';
            app.DeviceDropDownLabel = uilabel(app.UIFigure);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.UIFigure);
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDownValueChanged, true);
            app.backtostartButton = uibutton(app.UIFigure, 'push');
            app.backtostartButton.ButtonPushedFcn = createCallbackFcn(app, @backtostartButtonPushed, true);
            app.backtostartButton.Text = 'back to start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end